classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        Button                    matlab.ui.control.StateButton
        DivisionsEditFieldLabel   matlab.ui.control.Label
        DivisionsEditField        matlab.ui.control.NumericEditField
        Focus_point_1SliderLabel  matlab.ui.control.Label
        Focus_point_1Slider       matlab.ui.control.Slider
        Focus_point_2SliderLabel  matlab.ui.control.Label
        Focus_point_2Slider       matlab.ui.control.Slider
    end

properties(Access = public) 
data = [];
x = [];
y =[];
fp1_value = [];
fp2_value = [];
end


    methods (Access = private)

        % Callback function: Button, UIAxes
        function UIAxesButtonDown(app, event)
% filename =uigetfile('*./MATLAB Drive');
 [filename,filepath] = uigetfile('*./DESKTOP');
 [ app.x, app.y, app.data] = read_coc_map(fullfile(filepath,filename)); 
            miny1 = min(app.y);
            maxy1 = max(app.y);  
 app.Focus_point_1Slider.Limits = [miny1, maxy1];
 app.Focus_point_2Slider.Limits = [miny1, maxy1]; 

 
 figure(1);
 surf(app.x,app.y,app.data);
 shading interp;
 title('Blur radius chart');
 xlabel ('range ');
 ylabel ('d_o  ');
 zlabel ('Blur Radius (px)');
 [app.fp1_value , app.fp2_value ] = update_plot(app);


        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Value changed function: DivisionsEditField
        function DivisionsEditFieldValueChanged(app, event)
           
         update_plot(app);


        end

        % Value changed function: Focus_point_1Slider
        function Focus_point_1SliderValueChanged(app, event)
            
            update_plot(app);
             
        end

        % Value changed function: Focus_point_2Slider
        function Focus_point_2SliderValueChanged(app, event)
            
            update_plot(app); 
         
        end

        % Callback function
        function min_valueEditFieldValueChanged(app, event)
      
 
        end

        % Callback function
        function max_valueEditFieldValueChanged(app, event)


        end

        % Callback function
        function stepEditFieldValueChanged(app, event)
 
        end

        % Callback function
        function From_rangeEditFieldValueChanged(app, event)

        end

        % Callback function
        function To_rangeEditFieldValueChanged(app, event)
        
           
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X Range')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.Button.Text = 'Button';
            app.DivisionsEditFieldLabel = uilabel(app.UIFigure);
            app.DivisionsEditFieldLabel.Text = 'Divisions';
            app.DivisionsEditField = uieditfield(app.UIFigure, 'numeric');
            app.DivisionsEditField.Limits = [1 Inf];
            app.DivisionsEditField.ValueChangedFcn = createCallbackFcn(app, @DivisionsEditFieldValueChanged, true);
            app.DivisionsEditField.Value = 10;
            app.Focus_point_1SliderLabel = uilabel(app.UIFigure);
            app.Focus_point_1SliderLabel.Text = 'Focus_point_1';
            app.Focus_point_1Slider = uislider(app.UIFigure);
            app.Focus_point_1Slider.Limits = [500 1000];
            app.Focus_point_1Slider.ValueChangedFcn = createCallbackFcn(app, @Focus_point_1SliderValueChanged, true);
            app.Focus_point_1Slider.Value = 600;
            app.Focus_point_2SliderLabel = uilabel(app.UIFigure);
            app.Focus_point_2SliderLabel.Text = 'Focus_point_2';
            app.Focus_point_2Slider = uislider(app.UIFigure);
            app.Focus_point_2Slider.Limits = [600 1000];
            app.Focus_point_2Slider.MajorTicks = [600 640 680 720 760 800 840 880 920 960 1000];
            app.Focus_point_2Slider.ValueChangedFcn = createCallbackFcn(app, @Focus_point_2SliderValueChanged, true);
            app.Focus_point_2Slider.Value = 600;
        end
    end

    methods (Access = public)

        % Construct app
        function app = app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end